classdef mainController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaypointPublisherUIFigure   matlab.ui.Figure
        ModelNameDropDown           matlab.ui.control.DropDown
        ModelNameDropDownLabel      matlab.ui.control.Label
        VisualizationPanel          matlab.ui.container.Panel
        AnimateRobotButton          matlab.ui.control.Button
        ShowRobotButton             matlab.ui.control.Button
        SimulinkModelPanel          matlab.ui.container.Panel
        StopButton                  matlab.ui.control.Button
        SimulateButton              matlab.ui.control.Button
        UpdateWaypointsButton       matlab.ui.control.Button
        DownButton                  matlab.ui.control.Button
        UpButton                    matlab.ui.control.Button
        DelButton                   matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        MATLABWorkspacePanel        matlab.ui.container.Panel
        TimesEditField              matlab.ui.control.EditField
        TimesEditFieldLabel         matlab.ui.control.Label
        OrientationsEditField       matlab.ui.control.EditField
        OrientationsEditFieldLabel  matlab.ui.control.Label
        WriteVariablesButton        matlab.ui.control.Button
        PointsEditField             matlab.ui.control.EditField
        PointsEditFieldLabel        matlab.ui.control.Label
        ReadVariablesButton         matlab.ui.control.Button
        WaypointTable               matlab.ui.control.Table
        Title                       matlab.ui.control.Label
    end

    % Waypoint Publisher App
    % Copyright 2019 The MathWorks, Inc.
    
    properties (Access = private)           
        selectedWaypoint = 1;   % Currently selected waypoint on table
        
        robot;                  % Rigid Body Tree
        robotConfig;            % Rigid Body Tree joint configuration
        robotIK;                % Rigid Body Tree Inverse Kinematics solver
        robotIKWeights;         % Rigid Body Tree Inverse Kinematics weights
        robotIKInitGuess;       % Rigid Body Tree Inverse Kinematics initial guess
        robotEE;                % Rigid Body Tree End Effector frame
        
    end

    methods (Access = private)
    
        function createRigidBodyTree(app)
            % Creates a rigid body tree and IK solver from the robot and end effector name fields
            evalin('base',['load ' 'robot4dof']);
            app.robot = evalin('base','robot4dof');
            app.robot.DataFormat = 'row';
            app.robotIK = inverseKinematics('RigidBodyTree',app.robot);
            app.robotIK.SolverParameters.MaxIterations = 50;    % Limit the max iterations
            app.robotIKWeights = [1, 1, 1, 1, 1, 1];
            app.robotConfig = app.robot.homeConfiguration;
            app.robotIKInitGuess = app.robotConfig;
            app.robotEE = '06_Gripper1';    
        end
    
        function showRobot(app,idx)
            % Displays the waypoints, and the rigid body tree IK solution a provided waypoint index "idx"
            
            % Use the home configuration if the point is invalid
            if idx > size(app.WaypointTable.Data,1)
                app.robotConfig = app.robot.homeConfiguration;
            % Else, solve IK at the specified waypoint index
            else      
                position = app.WaypointTable.Data(idx,2:4);
                orient = app.WaypointTable.Data(idx,5:7);
                targetTform = trvec2tform(position)*eul2tform(orient);
                app.robotConfig = app.robotIK(app.robotEE,targetTform,app.robotIKWeights,app.robotIKInitGuess);            
            end
            % Display the rigid body tree
            show(app.robot,app.robotConfig,'Frames','off');
            app.plotWaypointMarkers(app.WaypointTable.Data,true); 
        end
    
        function plotWaypointMarkers(~,data,plotOrientations)
            % Helper method that plots waypoint markers
            hold on;
            plot3(data(:,2),data(:,3),data(:,4),'ro','MarkerSize',8,'LineWidth',2);
            if plotOrientations
               positions = data(:,2:4);
               orientations = data(:,5:7);
               plotTransforms(positions,eul2quat(orientations),'FrameSize',0.2);
            end
            hold off;
        end
        
        function exportVariablesToWorkspace(app)
            % Exports the waypoint and time data to the MATLAB base workspace.
            % Used when clicking the "To Workspace" and "Update Diagram" buttons
            assignin('base',app.TimesEditField.Value,app.WaypointTable.Data(:,1)');
            assignin('base',app.PointsEditField.Value,app.WaypointTable.Data(:,2:4)');
            assignin('base',app.OrientationsEditField.Value,app.WaypointTable.Data(:,5:7)');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Create Rigid Body Tree
            createRigidBodyTree(app);
            
            % Populate Table with sample data
            createVariables;
            try
                app.WaypointTable.Data = [waypointTimes', waypoints', orientations'];
            catch
                app.WaypointTable.Data = zeros(1,7);
            end
            app.selectedWaypoint = size(waypoints,1) + 1;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            idx = min(app.selectedWaypoint,size(app.WaypointTable.Data,1));
            app.WaypointTable.Data = [app.WaypointTable.Data(1:idx,:); ...
                                      zeros(1,7); ...
                                      app.WaypointTable.Data(idx+1:end,:)];
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            idx = app.selectedWaypoint;
            if size(app.WaypointTable.Data,1) > 1 && idx <= size(app.WaypointTable.Data,1)
                app.WaypointTable.Data(idx,:) = [];
            end
        end

        % Cell selection callback: WaypointTable
        function WaypointTableCellSelection(app, event)
            app.selectedWaypoint = event.Indices(1);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % Move the selected waypoint row up one spot unless it's the first one
            idx = app.selectedWaypoint;
            if idx > 1
                app.WaypointTable.Data([idx-1,idx],:) = app.WaypointTable.Data([idx,idx-1],:);
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % Move the selected waypoint down up one spot unless it's the first one
            idx = app.selectedWaypoint;
            if idx < size(app.WaypointTable.Data,1)
                app.WaypointTable.Data([idx,idx+1],:) = app.WaypointTable.Data([idx+1,idx],:);
            end           
        end

        % Button pushed function: ShowRobotButton
        function ShowRobotButtonPushed(app, event)
            % Display the Rigid Body Tree
            idx = app.selectedWaypoint;
            showRobot(app,idx);
        end

        % Cell edit callback: WaypointTable
        function WaypointTableCellEdit(app, event)
            % Evaluates a non-literal expression and sets the table data if the outcome is numeric
            data = evalin('caller',event.EditData);
            if isnumeric(data)
               app.WaypointTable.Data(event.Indices(1),event.Indices(2)) = data;
            end
        end

        % Button pushed function: AnimateRobotButton
        function AnimateRobotButtonPushed(app, event)
            % Animate one cycle of the rigid body tree going through the waypoints
            for idx = 1:size(app.WaypointTable.Data,1)
                showRobot(app,idx);
                pause(1);
            end
        end

        % Callback function: not associated with a component
        function RigidBodyTreeEditFieldValueChanged(app, event)
            createRigidBodyTree(app);
        end

        % Callback function: not associated with a component
        function EndEffectorNameEditFieldValueChanged(app, event)
            createRigidBodyTree(app);            
        end

        % Callback function
        function PublishButtonPushed(app, event)
            app.publishWaypoints();
        end

        % Button pushed function: WriteVariablesButton
        function WriteVariablesButtonPushed(app, event)
            try
                waypointVels = [waypointVels';0 0 0]';
                waypointAccels = zeros(size(waypointVels));
                % Acceleration times (trapezoidal only)
                waypointAccelTimes = diff(waypointTimes)/4;
                exportVariablesToWorkspace(app);
                msgbox('Saved waypoint data to workspace','Info');
            catch
                errordlg('Failed to save waypoint data to workspace','Info');
            end
        end

        % Button pushed function: ReadVariablesButton
        function ReadVariablesButtonPushed(app, event)
           try
               waypointTimes = evalin('base',app.TimesEditField.Value);
               waypoints = evalin('base',app.PointsEditField.Value);
               orientations = evalin('base',app.OrientationsEditField.Value);
               app.WaypointTable.Data = [waypointTimes', waypoints', orientations'];
               msgbox('Loaded waypoint data from workspace','Info');
           catch
               errordlg('Failed to load waypoint data from workspace','Info');
           end
        end

        % Button pushed function: UpdateWaypointsButton
        function UpdateWaypointsButtonPushed(app, event)
            exportVariablesToWorkspace(app)
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            mdlName = app.ModelNameDropDown.Value;
            if ~bdIsLoaded(mdlName)
                open_system(mdlName)
            end
            set_param(app.ModelNameDropDown.Value,'SimulationCommand','start');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param(app.ModelNameDropDown.Value,'SimulationCommand','stop');    
        end

        % Callback function
        function WaypointTopicEditFieldValueChanged(app, event)
            app.waypointPub = rospublisher(app.WaypointTopicEditField.Value,'geometry_msgs/PoseArray');            
        end

        % Callback function
        function WaypointTimeTopicEditFieldValueChanged(app, event)
            app.waypointTimePub = rospublisher(app.WaypointTimeTopicEditField.Value,'std_msgs/Float64MultiArray');            
        end

        % Callback function
        function AccelTimeTopicEditFieldValueChanged(app, event)
            app.accelTimePub = rospublisher(app.AccelTimeTopicEditField.Value,'std_msgs/Float64MultiArray');                        
        end

        % Callback function
        function ToFileButtonPushed(app, event)
        % Save waypoint information to a file
            try
                assignin('caller',app.TimesEditField.Value,app.WaypointTable.Data(:,1));
                assignin('caller',app.PointsEditField.Value,app.WaypointTable.Data(:,2:4));
                assignin('caller',app.OrientationsEditField.Value,app.WaypointTable.Data(:,5:7));
                save(filename,app.TimesEditField.Value,app.PointsEditField.Value, app.OrientationsEditField.Value);
                msgbox('Saved waypoint data to file','Info');
            catch
                errordlg('Failed to save waypoint data to workspace','Info');
            end          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaypointPublisherUIFigure = uifigure('Visible', 'off');
            app.WaypointPublisherUIFigure.Name = 'Waypoint Publisher';
            app.Title = uilabel(app.WaypointPublisherUIFigure);
            app.Title.Text = 'Table of Waypoints';
            app.WaypointTable = uitable(app.WaypointPublisherUIFigure);
            app.WaypointTable.ColumnName = {'Time'; 'X'; 'Y'; 'Z'; 'Yaw'; 'Pitch'; 'Roll'};
            app.WaypointTable.RowName = {};
            app.WaypointTable.ColumnEditable = true;
            app.WaypointTable.CellEditCallback = createCallbackFcn(app, @WaypointTableCellEdit, true);
            app.WaypointTable.CellSelectionCallback = createCallbackFcn(app, @WaypointTableCellSelection, true);
            app.MATLABWorkspacePanel = uipanel(app.WaypointPublisherUIFigure);
            app.MATLABWorkspacePanel.Title = 'MATLAB Workspace';
            app.ReadVariablesButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.ReadVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadVariablesButtonPushed, true);
            app.ReadVariablesButton.Text = 'Read Variables';
            app.PointsEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.PointsEditField.Value = 'waypoints';
            app.WriteVariablesButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.WriteVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVariablesButtonPushed, true);
            app.WriteVariablesButton.Text = 'Write Variables';
            app.OrientationsEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.OrientationsEditFieldLabel.Text = 'Orientations';
            app.OrientationsEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.OrientationsEditField.Value = 'orientations';
            app.TimesEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.TimesEditField.Value = 'waypointTimes';
            app.AddButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.DelButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = '-';
            app.UpButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '^';
            app.DownButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'v';
            app.SimulinkModelPanel = uipanel(app.WaypointPublisherUIFigure);
            app.SimulinkModelPanel.Title = 'Simulink Model';
            app.UpdateWaypointsButton = uibutton(app.SimulinkModelPanel, 'push');
            app.UpdateWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateWaypointsButtonPushed, true);
            app.UpdateWaypointsButton.Text = {'Update '; 'Waypoints'};
            app.SimulateButton = uibutton(app.SimulinkModelPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.SimulinkModelPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VisualizationPanel = uipanel(app.WaypointPublisherUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.ShowRobotButton = uibutton(app.VisualizationPanel, 'push');
            app.ShowRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRobotButtonPushed, true);
            app.ShowRobotButton.Text = {'Show Robot at '; 'Selected Point'};
            app.AnimateRobotButton = uibutton(app.VisualizationPanel, 'push');
            app.AnimateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateRobotButtonPushed, true);
            app.AnimateRobotButton.Text = {'Animate Robot '; 'at All Points'};
            app.ModelNameDropDownLabel = uilabel(app.WaypointPublisherUIFigure);
            app.ModelNameDropDownLabel.Text = 'Model Name';
            app.ModelNameDropDown = uidropdown(app.WaypointPublisherUIFigure);
            app.ModelNameDropDown.Items = {'RobotArmJointsPlot', 'RobotArmJointsComplete'};
            app.ModelNameDropDown.Value = 'RobotArmJointsPlot';
            % Show the figure after all components are created
            app.WaypointPublisherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaypointPublisherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaypointPublisherUIFigure)
        end
    end
end